classdef PropellerProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PropellerProjectUIFigure      matlab.ui.Figure
        HubandBladeDimensionsPanel    matlab.ui.container.Panel
        NoofBladesEditFieldLabel      matlab.ui.control.Label
        numNoofBlades                 matlab.ui.control.NumericEditField
        BladeDiametermEditFieldLabel  matlab.ui.control.Label
        numBladeDiameter              matlab.ui.control.NumericEditField
        HubRadiusmEditFieldLabel      matlab.ui.control.Label
        numHubRadius                  matlab.ui.control.NumericEditField
        AdditionalHubHeightmEditFieldLabel  matlab.ui.control.Label
        numAdditionalHubHeight        matlab.ui.control.NumericEditField
        NoseHeightmEditFieldLabel     matlab.ui.control.Label
        numNoseHeight                 matlab.ui.control.NumericEditField
        AerofoilDimensionsPanel       matlab.ui.container.Panel
        MaxCambersEditFieldLabel      matlab.ui.control.Label
        strMaxCambers                 matlab.ui.control.EditField
        MaxCamberPositionsEditFieldLabel  matlab.ui.control.Label
        strMaxCamberPositions         matlab.ui.control.EditField
        MaxThicknessesEditFieldLabel  matlab.ui.control.Label
        strMaxThicknesses             matlab.ui.control.EditField
        PitchAnglesEditFieldLabel     matlab.ui.control.Label
        strPitchAngles                matlab.ui.control.EditField
        ChordLengthsEditFieldLabel    matlab.ui.control.Label
        strChordLengths               matlab.ui.control.EditField
        UIAxesAerofoil                matlab.ui.control.UIAxes
        CrossSectionDisplayedEditFieldLabel  matlab.ui.control.Label
        numCrossSectionDisplayed      matlab.ui.control.NumericEditField
        CrossSectionDisplayedEditFieldLabel_2  matlab.ui.control.Label
        PlotAerofoilButton            matlab.ui.control.Button
        NumberofDiscretizationsPanel  matlab.ui.container.Panel
        NoBladePointsSliderLabel      matlab.ui.control.Label
        numNoBladePoints              matlab.ui.control.Slider
        NoGapPointsSliderLabel        matlab.ui.control.Label
        numNoGapPoints                matlab.ui.control.Slider
        NoofCrossSectionsEditFieldLabel  matlab.ui.control.Label
        numNoofCrossSections          matlab.ui.control.NumericEditField
        ExecuteButton                 matlab.ui.control.Button
        SetDefaultsButton             matlab.ui.control.Button
        RandomizeButton               matlab.ui.control.Button
        WritetoSTLButton              matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function PropData =  GetAppValues(app)
            PropData.MaxCamber = app.strMaxCambers.Value;
            PropData.PosMaxCamber = app.strMaxCamberPositions.Value;
            PropData.MaxThickness = app.strMaxThicknesses.Value;
            PropData.PitchAngle = app.strPitchAngles.Value;
            PropData.ChordLength = app.strChordLengths.Value;
            PropData.numBlades = app.numNoofBlades.Value;
            PropData.BladeDiameter = app.numBladeDiameter.Value;
            PropData.HubRadius = app.numHubRadius.Value;
            PropData.HubHeight = app.numAdditionalHubHeight.Value;
            PropData.CrossSectionDisplayed = app.numCrossSectionDisplayed.Value;
            PropData.NoDataPointsBlade = round(app.numNoBladePoints.Value);
            PropData.NoDataPointsGap = round(app.numNoGapPoints.Value);
            PropData.NoCrossSections = app.numNoofCrossSections.Value;
            PropData.NoseHeight = app.numNoseHeight.Value;
        end
        
        function SetAppValues(app, PropData)
            app.strMaxCambers.Value = PropData.MaxCamber;
            app.strMaxCamberPositions.Value = PropData.PosMaxCamber;
            app.strMaxThicknesses.Value = PropData.MaxThickness;
            app.strPitchAngles.Value = PropData.PitchAngle;
            app.strChordLengths.Value = PropData.ChordLength;
            app.numNoofBlades.Value = PropData.numBlades;
            app.numBladeDiameter.Value = PropData.BladeDiameter;
            app.numHubRadius.Value = PropData.HubRadius;
            app.numAdditionalHubHeight.Value = PropData.HubHeight;
            app.numCrossSectionDisplayed.Value = PropData.CrossSectionDisplayed;
            app.numNoBladePoints.Value = PropData.NoDataPointsBlade;
            app.numNoGapPoints.Value = PropData.NoDataPointsGap;
            app.numNoofCrossSections.Value = PropData.NoCrossSections;
            app.numNoseHeight.Value = PropData.NoseHeight;
        end
        
    end
    
    
    methods (Access = public, Static)
        
        function PropData = DefaultPropData()
            PropData.numBlades    = 5;          % Prop.numBlades = number of blades on propeller
            PropData.BladeDiameter = 20;        % PropData.BladeDiameter = length of blade
            PropData.HubRadius = 1 ;           % PropData.HubRadius = radius of the hub
            PropData.HubHeight = 0.1;          % PropData.HubHeight = the additional height on either side of the blade hub
            PropData.NoseHeight = 1;
            
            %% Strings to Matrix Inputs on GUI
            
            % Default values for propeller geometry
            PropData.MaxCamber = '1,20,15,2';
            PropData.PosMaxCamber = '4,3,0.1';
            PropData.MaxThickness = '40, 25.6, 18.66667, 12.26667, 8, 3.2,0.1';
            PropData.PitchAngle = '56,42,32,26,24,22';
            PropData.ChordLength = '2.072727273,2.509090909,2.836363636,3,2.727272727,0.1';
            
            %% Settings inputs on GUI
            
            PropData.NoDataPointsBlade = 100; % PropData.NoDataPointsBlade = number of data points that define blades
            PropData.NoDataPointsGap = 50;    % PropData.NoDataPointsGap = number of data points that define gap between blades
            PropData.NoCrossSections = 350;              % NoCrossSections = number of aerofoil cross-sections used to build blade along its length
            
            PropData.CrossSectionDisplayed = 50;
        end
        
        function PropData = RandomizePropData()
            PropData.numBlades    = randi([2 20], 1,1);          % Prop.numBlades = number of blades on propeller
            PropData.BladeDiameter = randi([10 100], 1,1)/10+10;        % PropData.BladeDiameter = length of blade
            PropData.HubRadius = randi([1 10], 1,1)/10+1;           % PropData.HubRadius = radius of the hub
            PropData.HubHeight = randi([1 10], 1,1)/10;          % PropData.HubHeight = the additional height on either side of the blade hub
            PropData.NoseHeight = randi([1 10], 1,1)/10+1;
            
            %% Strings to Matrix Inputs on GUI
            
            % Default values for propeller geometry
            PropData.MaxCamber = [randi([0 100], 1,1)/10,randi([0 100], 1,1)/10,randi([0 100], 1,1)/10,randi([0 100], 1,1)/10];
            PropData.PosMaxCamber = [randi([0 90], 1,1),randi([0 90], 1,1),randi([0 90], 1,1), randi([0 90], 1,1)];
            PropData.MaxThickness = [randi([1 40], 1,1), randi([1 40], 1,1), randi([1 40], 1,1), randi([1 40], 1,1), randi([1 40], 1,1)];
            PropData.PitchAngle = [randi([0 60], 1,1),randi([0 60], 1,1),randi([0 60], 1,1),randi([0 60], 1,1)];
            PropData.ChordLength = [randi([1 100], 1,1)/100+1,randi([1 100], 1,1)/100+1,randi([1 100], 1,1)/100+1, randi([1 100], 1,1)/100+1];
            
            %% Settings inputs on GUI
            
            PropData.NoDataPointsBlade = randi([10 150], 1,1); % PropData.NoDataPointsBlade = number of data points that define blades
            PropData.NoDataPointsGap = randi([10 150], 1,1);    % PropData.NoDataPointsGap = number of data points that define gap between blades
            PropData.NoCrossSections = randi([2 500], 1,1);              % NoCrossSections = number of aerofoil cross-sections used to build blade along its length
            
            PropData.CrossSectionDisplayed = 50;
        end
        
        function Mat = ConvertStr2Mat(Str)
            Mat = eval(['[' Str ']']);
        end
        
        function Str = ConvertMat2Str(Mat)
            Str = num2str(Mat(1));
            for i = 2:length(Mat)
                Str = [Str ', ' num2str(Mat(i))];
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            PropData = app.DefaultPropData();
            SetAppValues(app, PropData);
            
            %Convert String Values to Matrix
            PropData.MaxCamber = app.ConvertStr2Mat(PropData.MaxCamber);
            PropData.PosMaxCamber = app.ConvertStr2Mat(PropData.PosMaxCamber);
            PropData.MaxThickness = app.ConvertStr2Mat(PropData.MaxThickness);
            PropData.PitchAngle = app.ConvertStr2Mat(PropData.PitchAngle);
            PropData.ChordLength = app.ConvertStr2Mat(PropData.ChordLength);
            
            [xU, yU, xL, yL] = AerofoilViewFunction(PropData);
            
            plot(app.UIAxesAerofoil,xU, yU, 'b-', xL, yL, 'r-');
            axis(app.UIAxesAerofoil, [0,1,-inf,inf])
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            PropData = GetAppValues(app);
            
            %Convert String Values to Matrix
            PropData.MaxCamber = app.ConvertStr2Mat(PropData.MaxCamber);
            PropData.PosMaxCamber = app.ConvertStr2Mat(PropData.PosMaxCamber);
            PropData.MaxThickness = app.ConvertStr2Mat(PropData.MaxThickness);
            PropData.PitchAngle = app.ConvertStr2Mat(PropData.PitchAngle);
            PropData.ChordLength = app.ConvertStr2Mat(PropData.ChordLength);
            
            PropellerFunction(PropData);
        end

        % Button pushed function: SetDefaultsButton
        function SetDefaultsButtonPushed(app, event)
            PropData = app.DefaultPropData();
            SetAppValues(app, PropData);
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
            PropData = app.RandomizePropData();
            
            %Convert Matrix Values to String
            PropData.MaxCamber = app.ConvertMat2Str(PropData.MaxCamber);
            PropData.PosMaxCamber = app.ConvertMat2Str(PropData.PosMaxCamber);
            PropData.MaxThickness = app.ConvertMat2Str(PropData.MaxThickness);
            PropData.PitchAngle = app.ConvertMat2Str(PropData.PitchAngle);
            PropData.ChordLength = app.ConvertMat2Str(PropData.ChordLength);
            
            SetAppValues(app, PropData);
        end

        % Value changed function: numCrossSectionDisplayed
        function numCrossSectionDisplayedValueChanged(app, event)
            
        end

        % Button pushed function: PlotAerofoilButton
        function PlotAerofoilButtonPushed(app, event)
            PropData = GetAppValues(app);
            
            %Convert String Values to Matrix
            PropData.MaxCamber = app.ConvertStr2Mat(PropData.MaxCamber);
            PropData.PosMaxCamber = app.ConvertStr2Mat(PropData.PosMaxCamber);
            PropData.MaxThickness = app.ConvertStr2Mat(PropData.MaxThickness);
            PropData.PitchAngle = app.ConvertStr2Mat(PropData.PitchAngle);
            PropData.ChordLength = app.ConvertStr2Mat(PropData.ChordLength);
            
            [xU, yU, xL, yL] = AerofoilViewFunction(PropData);
            
            plot(app.UIAxesAerofoil,xU, yU, 'b-', xL, yL, 'r-');
            axis(app.UIAxesAerofoil, [0,1,-inf,inf])
            axis(app.UIAxesAerofoil, 'equal')
        end

        % Button pushed function: WritetoSTLButton
        function WritetoSTLButtonPushed(app, event)
            
            [FileName,PathName,~] = uiputfile('*.stl', 'Save STL File');
            %If User Presses Cancel
            if isnumeric(FileName) || isnumeric(PathName)
                return;
            end;
            
            STLWritePath = fullfile(PathName, FileName);
            
            %Get Values from GUI and Execute
            PropData = GetAppValues(app);
            
            %Convert String Values to Matrix
            PropData.MaxCamber = app.ConvertStr2Mat(PropData.MaxCamber);
            PropData.PosMaxCamber = app.ConvertStr2Mat(PropData.PosMaxCamber);
            PropData.MaxThickness = app.ConvertStr2Mat(PropData.MaxThickness);
            PropData.PitchAngle = app.ConvertStr2Mat(PropData.PitchAngle);
            PropData.ChordLength = app.ConvertStr2Mat(PropData.ChordLength);
            
            OutputCoordinates = PropellerFunction(PropData);
            
            %Write to STL path 
            surf2stl(STLWritePath,OutputCoordinates.X,OutputCoordinates.Y,OutputCoordinates.Z)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PropellerProjectUIFigure = uifigure('Visible', 'off');
            app.PropellerProjectUIFigure.Name = 'Propeller Project';
            app.HubandBladeDimensionsPanel = uipanel(app.PropellerProjectUIFigure);
            app.HubandBladeDimensionsPanel.Title = 'Hub and Blade Dimensions';
            app.NoofBladesEditFieldLabel = uilabel(app.HubandBladeDimensionsPanel);
            app.NoofBladesEditFieldLabel.Text = 'No. of Blades';
            app.numNoofBlades = uieditfield(app.HubandBladeDimensionsPanel, 'numeric');
            app.numNoofBlades.Limits = [1 Inf];
            app.numNoofBlades.Value = 1;
            app.BladeDiametermEditFieldLabel = uilabel(app.HubandBladeDimensionsPanel);
            app.BladeDiametermEditFieldLabel.Text = 'Blade Diameter (m)';
            app.numBladeDiameter = uieditfield(app.HubandBladeDimensionsPanel, 'numeric');
            app.numBladeDiameter.Limits = [0.1 Inf];
            app.numBladeDiameter.Value = 0.1;
            app.HubRadiusmEditFieldLabel = uilabel(app.HubandBladeDimensionsPanel);
            app.HubRadiusmEditFieldLabel.Text = 'Hub Radius (m)';
            app.numHubRadius = uieditfield(app.HubandBladeDimensionsPanel, 'numeric');
            app.numHubRadius.Limits = [0.1 Inf];
            app.numHubRadius.Value = 0.1;
            app.AdditionalHubHeightmEditFieldLabel = uilabel(app.HubandBladeDimensionsPanel);
            app.AdditionalHubHeightmEditFieldLabel.Text = 'Additional Hub Height (m)';
            app.numAdditionalHubHeight = uieditfield(app.HubandBladeDimensionsPanel, 'numeric');
            app.numAdditionalHubHeight.Limits = [0 Inf];
            app.NoseHeightmEditFieldLabel = uilabel(app.HubandBladeDimensionsPanel);
            app.NoseHeightmEditFieldLabel.Text = 'Nose Height (m)';
            app.numNoseHeight = uieditfield(app.HubandBladeDimensionsPanel, 'numeric');
            app.numNoseHeight.Limits = [0 Inf];
            app.AerofoilDimensionsPanel = uipanel(app.PropellerProjectUIFigure);
            app.AerofoilDimensionsPanel.Title = 'Aerofoil Dimensions';
            app.MaxCambersEditFieldLabel = uilabel(app.AerofoilDimensionsPanel);
            app.MaxCambersEditFieldLabel.Text = 'Max Cambers (%)';
            app.strMaxCambers = uieditfield(app.AerofoilDimensionsPanel, 'text');
            app.MaxCamberPositionsEditFieldLabel = uilabel(app.AerofoilDimensionsPanel);
            app.MaxCamberPositionsEditFieldLabel.Text = 'Max Camber Positions (%)';
            app.strMaxCamberPositions = uieditfield(app.AerofoilDimensionsPanel, 'text');
            app.MaxThicknessesEditFieldLabel = uilabel(app.AerofoilDimensionsPanel);
            app.MaxThicknessesEditFieldLabel.Text = 'Max Thicknesses (%)';
            app.strMaxThicknesses = uieditfield(app.AerofoilDimensionsPanel, 'text');
            app.PitchAnglesEditFieldLabel = uilabel(app.AerofoilDimensionsPanel);
            app.PitchAnglesEditFieldLabel.Text = 'Pitch Angles';
            app.strPitchAngles = uieditfield(app.AerofoilDimensionsPanel, 'text');
            app.ChordLengthsEditFieldLabel = uilabel(app.AerofoilDimensionsPanel);
            app.ChordLengthsEditFieldLabel.Text = 'Chord Lengths';
            app.strChordLengths = uieditfield(app.AerofoilDimensionsPanel, 'text');
            app.UIAxesAerofoil = uiaxes(app.AerofoilDimensionsPanel);
            app.CrossSectionDisplayedEditFieldLabel = uilabel(app.AerofoilDimensionsPanel);
            app.CrossSectionDisplayedEditFieldLabel.Text = 'Cross-Section Displayed (%) ';
            app.numCrossSectionDisplayed = uieditfield(app.AerofoilDimensionsPanel, 'numeric');
            app.numCrossSectionDisplayed.Limits = [0 100];
            app.numCrossSectionDisplayed.ValueChangedFcn = createCallbackFcn(app, @numCrossSectionDisplayedValueChanged, true);
            app.CrossSectionDisplayedEditFieldLabel_2 = uilabel(app.AerofoilDimensionsPanel);
            app.CrossSectionDisplayedEditFieldLabel_2.Text = 'At Hub = 0 %, Blade Tip = 100%  ';
            app.PlotAerofoilButton = uibutton(app.AerofoilDimensionsPanel, 'push');
            app.PlotAerofoilButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAerofoilButtonPushed, true);
            app.PlotAerofoilButton.Text = 'Plot Aerofoil';
            app.NumberofDiscretizationsPanel = uipanel(app.PropellerProjectUIFigure);
            app.NumberofDiscretizationsPanel.Title = 'Number of Discretizations';
            app.NoBladePointsSliderLabel = uilabel(app.NumberofDiscretizationsPanel);
            app.NoBladePointsSliderLabel.Text = 'No. Blade Points';
            app.numNoBladePoints = uislider(app.NumberofDiscretizationsPanel);
            app.numNoBladePoints.Limits = [10 150];
            app.numNoBladePoints.Value = 50;
            app.NoGapPointsSliderLabel = uilabel(app.NumberofDiscretizationsPanel);
            app.NoGapPointsSliderLabel.Text = 'No. Gap Points';
            app.numNoGapPoints = uislider(app.NumberofDiscretizationsPanel);
            app.numNoGapPoints.Limits = [10 150];
            app.numNoGapPoints.Value = 50;
            app.NoofCrossSectionsEditFieldLabel = uilabel(app.NumberofDiscretizationsPanel);
            app.NoofCrossSectionsEditFieldLabel.Text = 'No. of Cross Sections';
            app.numNoofCrossSections = uieditfield(app.NumberofDiscretizationsPanel, 'numeric');
            app.numNoofCrossSections.Limits = [2 Inf];
            app.numNoofCrossSections.Value = 2;
            app.ExecuteButton = uibutton(app.PropellerProjectUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.SetDefaultsButton = uibutton(app.PropellerProjectUIFigure, 'push');
            app.SetDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultsButtonPushed, true);
            app.SetDefaultsButton.Text = 'Set Defaults';
            app.RandomizeButton = uibutton(app.PropellerProjectUIFigure, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Text = 'Randomize';
            app.WritetoSTLButton = uibutton(app.PropellerProjectUIFigure, 'push');
            app.WritetoSTLButton.ButtonPushedFcn = createCallbackFcn(app, @WritetoSTLButtonPushed, true);
            app.WritetoSTLButton.Text = 'Write to STL';
            % Show the figure after all components are created
            app.PropellerProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PropellerProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PropellerProjectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PropellerProjectUIFigure)
        end
    end
end